classdef massspring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        initialpositionSlider       matlab.ui.control.Slider
        initialpositionSliderLabel  matlab.ui.control.Label
        FrequencySlider             matlab.ui.control.Slider
        FrequencySliderLabel        matlab.ui.control.Label
        AmplitudeSliderLabel_2      matlab.ui.control.Label
        PhasePortraitCheckBox       matlab.ui.control.CheckBox
        timeSlider                  matlab.ui.control.Slider
        timeSliderLabel             matlab.ui.control.Label
        AmplitudeSlider             matlab.ui.control.Slider
        AmplitudeSliderLabel        matlab.ui.control.Label
        dampingSlider               matlab.ui.control.Slider
        dampingSliderLabel          matlab.ui.control.Label
        springconstantSlider        matlab.ui.control.Slider
        springconstantSliderLabel   matlab.ui.control.Label
        massSlider                  matlab.ui.control.Slider
        massSliderLabel             matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        UIAxes                      matlab.ui.control.UIAxes
    end


    methods (Access = public)
    
        function showmatrix(app)
            a = get(app.AmplitudeSlider,'Value');
            k = get(app.springconstantSlider,'Value');
            b = get(app.dampingSlider,'Value');
            m = get(app.massSlider,'Value');
            w = get(app.FrequencySlider,'Value');
            init = get(app.initialpositionSlider,'Value');
            timemax=get(app.timeSlider,'Value');
            
            
            f=app.UIFigure;
            cla(app.UIAxes);
           
            hold(app.UIAxes,'on');
            
              app.UIAxes.XColor = [0 0 0];
            app.UIAxes.YColor = [0 0 0];
           
            app.UIAxes.YTick = [-10,-8,-6,-4,-2,0,2,4,6,8,10];
            app.UIAxes.YTickLabel=[-10,-8,-6,-4,-2,0,2,4,6,8,10];
            app.UIAxes.XLim = [0 timemax];
            xlabel(app.UIAxes,'t');
            ylabel(app.UIAxes,'x(t)');
            X=0:5:timemax;
            app.UIAxes.XTick = X;
            app.UIAxes.XTickLabel=round(X);
            odeFcnAnon = @(t, y) [y(2); 1/m * (a*cos(w*t)-k * y(1) - b * y(2))];
            T = 0:0.1:timemax;
            [~, Y] = ode45(odeFcnAnon, T, [init, 1]);
            plot(app.UIAxes,T, Y(:,1));

            
           phase=get(app.PhasePortraitCheckBox,'Value');
           if phase ==1
               cla(app.UIAxes);
               app.UIAxes.XLim = [-10 10];
               app.UIAxes.YLim = [-10 10];
                
            
            X=-10:2:10;
            app.UIAxes.XTick = X;
            app.UIAxes.XTickLabel=round(X);
            app.UIAxes.YTick = X;
            app.UIAxes.YTickLabel=round(X);
           
            xlabel(app.UIAxes,'x(t)');
            ylabel(app.UIAxes,'x''(t)');
           plot(app.UIAxes,Y(:,1),Y(:,2))
            end;


           hold(app.UIAxes,'off');
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             showmatrix(app);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            showmatrix(app);
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            showmatrix(app);
        end

        % Value changed function: PhasePortraitCheckBox
        function PhasePortraitCheckBoxValueChanged(app, event)
            value = app.PhasePortraitCheckBox.Value;
            showmatrix(app);
        end

        % Callback function
        function PickamessageDropDownValueChanged(app, event)
           app.m= app.PickamessageDropDown.Value;
            showmatrix(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
    
        end

        % Value changed function: dampingSlider
        function dampingSliderValueChanged(app, event)
            value = app.dampingSlider.Value;
            showmatrix(app);
        end

        % Value changed function: initialpositionSlider
        function initialpositionSliderValueChanged(app, event)
            value = app.initialpositionSlider.Value;
            showmatrix(app);
        end

        % Value changed function: massSlider
        function massSliderValueChanged(app, event)
            value = app.massSlider.Value;
            showmatrix(app);
        end

        % Value changed function: springconstantSlider
        function springconstantSliderValueChanged(app, event)
            value = app.springconstantSlider.Value;
            showmatrix(app);
        end

        % Value changed function: timeSlider
        function timeSliderValueChanged(app, event)
            value = app.timeSlider.Value;
            showmatrix(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [0 2 4 6 8 10];
            app.UIAxes.YTick = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.YTickLabel = {'-100'; '-90'; '-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Forced Oscillator with Damping'};
            app.massSliderLabel = uilabel(app.UIFigure);
            app.massSliderLabel.Text = 'mass';
            app.massSlider = uislider(app.UIFigure);
            app.massSlider.Limits = [1 10];
            app.massSlider.ValueChangedFcn = createCallbackFcn(app, @massSliderValueChanged, true);
            app.massSlider.Value = 1;
            app.springconstantSliderLabel = uilabel(app.UIFigure);
            app.springconstantSliderLabel.Text = 'spring constant';
            app.springconstantSlider = uislider(app.UIFigure);
            app.springconstantSlider.Limits = [1 10];
            app.springconstantSlider.ValueChangedFcn = createCallbackFcn(app, @springconstantSliderValueChanged, true);
            app.springconstantSlider.Value = 2;
            app.dampingSliderLabel = uilabel(app.UIFigure);
            app.dampingSliderLabel.Text = 'damping';
            app.dampingSlider = uislider(app.UIFigure);
            app.dampingSlider.Limits = [-1 5];
            app.dampingSlider.ValueChangedFcn = createCallbackFcn(app, @dampingSliderValueChanged, true);
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.Value = 1;
            app.timeSliderLabel = uilabel(app.UIFigure);
            app.timeSliderLabel.Text = 'time';
            app.timeSlider = uislider(app.UIFigure);
            app.timeSlider.Limits = [10 50];
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderValueChanged, true);
            app.timeSlider.Value = 10;
            app.PhasePortraitCheckBox = uicheckbox(app.UIFigure);
            app.PhasePortraitCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhasePortraitCheckBoxValueChanged, true);
            app.PhasePortraitCheckBox.Text = 'Phase Portrait';
            app.AmplitudeSliderLabel_2 = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel_2.Text = 'Forcing Function';
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [0 5];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.initialpositionSliderLabel = uilabel(app.UIFigure);
            app.initialpositionSliderLabel.Text = 'initial position';
            app.initialpositionSlider = uislider(app.UIFigure);
            app.initialpositionSlider.Limits = [0 10];
            app.initialpositionSlider.ValueChangedFcn = createCallbackFcn(app, @initialpositionSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = massspring

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end